classdef Copy_of_appp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STARSUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ManualTab                       matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        InputsTab                       matlab.ui.container.Tab
        MainInputsPanel                 matlab.ui.container.Panel
        ThrustinNLabel                  matlab.ui.control.Label
        ThrustinNEditField              matlab.ui.control.NumericEditField
        ChamberPressureinPaEditFieldLabel  matlab.ui.control.Label
        ChamberPressureinPaEditField    matlab.ui.control.NumericEditField
        PropellantsDropDownLabel        matlab.ui.control.Label
        PropellantsDropDown             matlab.ui.control.DropDown
        InitialMassinKgEditFieldLabel   matlab.ui.control.Label
        InitialMassinKgEditField        matlab.ui.control.NumericEditField
        MissionDeltaVinmsEditFieldLabel  matlab.ui.control.Label
        MissionDeltaVinmsEditField      matlab.ui.control.NumericEditField
        SelectionsPanel                 matlab.ui.container.Panel
        BellCheckBox                    matlab.ui.control.CheckBox
        ConicalCheckBox                 matlab.ui.control.CheckBox
        AluminiumCheckBox               matlab.ui.control.CheckBox
        TitaniumCheckBox                matlab.ui.control.CheckBox
        TurbopumpCheckBox               matlab.ui.control.CheckBox
        PressureFeedCheckBox            matlab.ui.control.CheckBox
        NozzleSelectionLabel            matlab.ui.control.Label
        MaterialSelectionLabel          matlab.ui.control.Label
        FeedSystemLabel                 matlab.ui.control.Label
        SealevelCheckBox                matlab.ui.control.CheckBox
        VacuumCheckBox                  matlab.ui.control.CheckBox
        MissionLabel                    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        ShowPropellantsCheckBox         matlab.ui.control.CheckBox
        FlowoftheprogramTab             matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        OutputDisplayTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        NozzleTab                       matlab.ui.container.Tab
        ExpansionRatioEditFieldLabel    matlab.ui.control.Label
        ExpansionRatioEditField         matlab.ui.control.NumericEditField
        MassflowrateinkgsEditFieldLabel  matlab.ui.control.Label
        MassflowrateinkgsEditField      matlab.ui.control.NumericEditField
        SpecificImpulseinsEditFieldLabel  matlab.ui.control.Label
        SpecificImpulseinsEditField     matlab.ui.control.NumericEditField
        CoefficientofThrustEditFieldLabel  matlab.ui.control.Label
        CoefficientofThrustEditField    matlab.ui.control.NumericEditField
        ExitCharacteristicsPanel        matlab.ui.container.Panel
        ExitdiameterinmEditFieldLabel   matlab.ui.control.Label
        ExitdiameterinmEditField        matlab.ui.control.NumericEditField
        ExitMachnumberEditFieldLabel    matlab.ui.control.Label
        ExitMachnumberEditField         matlab.ui.control.NumericEditField
        ExitVelocityinmsEditFieldLabel  matlab.ui.control.Label
        ExitVelocityinmsEditField       matlab.ui.control.NumericEditField
        ThroatCharacteristicsPanel      matlab.ui.container.Panel
        ThroatAreainm2EditFieldLabel    matlab.ui.control.Label
        ThroatAreainm2EditField         matlab.ui.control.NumericEditField
        ThroatDiameterinmEditFieldLabel  matlab.ui.control.Label
        ThroatDiameterinmEditField      matlab.ui.control.NumericEditField
        NozzleContourTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ChamberTab                      matlab.ui.container.Tab
        ChamberPanel                    matlab.ui.container.Panel
        Volumeinm3EditFieldLabel        matlab.ui.control.Label
        Volumeinm3EditField             matlab.ui.control.NumericEditField
        LengthinmEditFieldLabel         matlab.ui.control.Label
        LengthinmEditField              matlab.ui.control.NumericEditField
        DiameterinmEditFieldLabel       matlab.ui.control.Label
        DiameterinmEditField            matlab.ui.control.NumericEditField
        ThicknessinmEditFieldLabel      matlab.ui.control.Label
        ThicknessinmEditField           matlab.ui.control.NumericEditField
        MeanDiameterinmEditFieldLabel   matlab.ui.control.Label
        MeanDiameterinmEditField        matlab.ui.control.NumericEditField
        CylindricalpartPanel            matlab.ui.container.Panel
        Volumeinm3EditField_2Label      matlab.ui.control.Label
        Volumeinm3EditField_2           matlab.ui.control.NumericEditField
        LengthinmEditField_2Label       matlab.ui.control.Label
        LengthinmEditField_2            matlab.ui.control.NumericEditField
        InjectorTab                     matlab.ui.container.Tab
        OxidizerCharacteristicsPanel    matlab.ui.container.Panel
        PressureDropOxidizerinPaEditFieldLabel  matlab.ui.control.Label
        PressureDropOxidizerinPaEditField  matlab.ui.control.NumericEditField
        VelocityfromOxidizerorificeinmsEditFieldLabel  matlab.ui.control.Label
        VelocityfromOxidizerorificeinmsEditField  matlab.ui.control.NumericEditField
        DiameterofOxidizerorificeinmEditFieldLabel  matlab.ui.control.Label
        DiameterofOxidizerorificeinmEditField  matlab.ui.control.NumericEditField
        NumberofOxidizerholesEditFieldLabel  matlab.ui.control.Label
        NumberofOxidizerholesEditField  matlab.ui.control.NumericEditField
        FuelCharacteristicsPanel        matlab.ui.container.Panel
        PressureDropFuelinPaEditFieldLabel  matlab.ui.control.Label
        PressureDropFuelinPaEditField   matlab.ui.control.NumericEditField
        VelocityfromFuelorificeinmsEditFieldLabel  matlab.ui.control.Label
        VelocityfromFuelorificeinmsEditField  matlab.ui.control.NumericEditField
        DiameterofFuelorificeinmEditFieldLabel  matlab.ui.control.Label
        DiameterofFuelorificeinmEditField  matlab.ui.control.NumericEditField
        NumberofFuelholesEditFieldLabel  matlab.ui.control.Label
        NumberofFuelholesEditField      matlab.ui.control.NumericEditField
        FeedsystemTab                   matlab.ui.container.Tab
        TurbopumpFeedPanel              matlab.ui.container.Panel
        PowerofturbineinWEditFieldLabel  matlab.ui.control.Label
        PowerofturbineinWEditField      matlab.ui.control.NumericEditField
        MassflowturbineinkgsEditFieldLabel  matlab.ui.control.Label
        MassflowturbineinkgsEditField   matlab.ui.control.NumericEditField
        PowerOxidizerpumpinWEditFieldLabel  matlab.ui.control.Label
        PowerOxidizerpumpinWEditField   matlab.ui.control.NumericEditField
        PowerFuelpumpinWEditFieldLabel  matlab.ui.control.Label
        PowerFuelpumpinWEditField       matlab.ui.control.NumericEditField
        PressureFeedPanel               matlab.ui.container.Panel
        Pressurizingtankvolumeinm3EditFieldLabel  matlab.ui.control.Label
        Pressurizingtankvolumeinm3EditField  matlab.ui.control.NumericEditField
        PressurizingtankmassinkgEditFieldLabel  matlab.ui.control.Label
        PressurizingtankmassinkgEditField  matlab.ui.control.NumericEditField
        TankradiusinmEditFieldLabel     matlab.ui.control.Label
        TankradiusinmEditField          matlab.ui.control.NumericEditField
        TankthicknessinmEditFieldLabel  matlab.ui.control.Label
        TankthicknessinmEditField       matlab.ui.control.NumericEditField
        PressurizinggasmassinkgEditFieldLabel  matlab.ui.control.Label
        PressurizinggasmassinkgEditField  matlab.ui.control.NumericEditField
        TankDesignTab                   matlab.ui.container.Tab
        TotalPanel                      matlab.ui.container.Panel
        MassinkgEditFieldLabel          matlab.ui.control.Label
        MassinkgEditField               matlab.ui.control.NumericEditField
        Volumeinm3EditField_3Label      matlab.ui.control.Label
        Volumeinm3EditField_3           matlab.ui.control.NumericEditField
        OxidizertankPanel               matlab.ui.container.Panel
        MassinkgEditField_2Label        matlab.ui.control.Label
        MassinkgEditField_2             matlab.ui.control.NumericEditField
        ThicknessinmEditField_2Label    matlab.ui.control.Label
        ThicknessinmEditField_2         matlab.ui.control.NumericEditField
        Volumeinm3EditField_4Label      matlab.ui.control.Label
        Volumeinm3EditField_4           matlab.ui.control.NumericEditField
        RadiusinmEditFieldLabel         matlab.ui.control.Label
        RadiusinmEditField              matlab.ui.control.NumericEditField
        FueltankPanel                   matlab.ui.container.Panel
        MassinkgEditField_3Label        matlab.ui.control.Label
        MassinkgEditField_3             matlab.ui.control.NumericEditField
        Volumeinm3EditField_5Label      matlab.ui.control.Label
        Volumeinm3EditField_5           matlab.ui.control.NumericEditField
        ThicknessinmEditField_3Label    matlab.ui.control.Label
        ThicknessinmEditField_3         matlab.ui.control.NumericEditField
        RadiusinmEditField_2Label       matlab.ui.control.Label
        RadiusinmEditField_2            matlab.ui.control.NumericEditField
        RunthetooltoseetheoutputsLabel  matlab.ui.control.Label
        AboutUsTab                      matlab.ui.container.Tab
        TextArea_3                      matlab.ui.control.TextArea
        TeamDHRUVLabel                  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
    end


    properties (Access = public)
       FT,p_1,option,minitial,deltav,F_choice
       
    end

    methods (Access = private)
        
        % Bell nozzle and Chamber function
        function[Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_bell(app,T_1,R,M,g,L_starm,p_e,p_a)       
            
            %Description:
            
            theta_conver=45;
            Ve = (((2*g*R*T_1)/(M*g-M))*(1-(p_e/app.p_1.Value)^((g-1)/g)))^0.5;        %Exit velocity
            Me = sqrt((2/(g-1))*((app.p_1.Value/p_e)^((g-1)/g)-1));                    %Exit Mach number
            e= (1/Me)*(((2/(g+1))*(1+((g-1)/2)*Me^2)))^((g+1)/(2*(g-1)));              %Expansion ratio
            a = (2*g^2)/(g-1);
            b = (2/(g+1))^((g+1)/(g-1));
            c = 1-(p_e/app.p_1.Value)^((g-1)/g);
            Cf = sqrt(a*b*c)+(((p_e-p_a)/app.p_1.Value)*(e));                           %Thrust coeff
            Th_Area = app.FT.Value/(app.p_1.Value * Cf);                                %Throat area
            A_exit = Th_Area * e;                                                       %Exit area
            Dia_exit=((4*A_exit)/3.14)^(0.5);                                           %Exit diameter
            Th_Dia=((4* Th_Area)/3.14)^(0.5);                                           %Throat diameter
            Rt= Th_Dia/2;
            m_dot1 = (Th_Area*app.p_1.Value)/((R/M)*T_1)^0.5;
            m_dot2 = g*((2/(g+1))^((g+1)/(g-1)));
            m_dot = m_dot1 * m_dot2^0.5 ;                                               %Mass flow rate
            Isp = app.FT.Value/(m_dot * 9.81);                                          %Specific impulse
            
            %Nozzle plots
            theta = 50; %deg
            theta_n = theta*pi/180; %rad
            area_ratio = A_exit/Th_Area;
            Ln = (0.8*((area_ratio)^(0.5)-1)*(Rt))/tand(15);
            
            % Angles for First Curve
            Angle_FC=-3*pi/4;
            FC_step=(-pi/2-Angle_FC)/9;
            theta_FC=(-3*pi/4):FC_step:(-pi/2);
            
            % Coordinates for First Curve
            x_FC=cos(theta_FC)*1.5*Rt;
            y_FC=sin(theta_FC)*1.5*Rt+(1.5*Rt+Rt);
            x_FC1 = x_FC;
            y_FC1 = y_FC;
          
            % Angle for Second Curve
            Angle_SC=-pi/2;
            SC_step=((theta_n-pi/2)-Angle_SC)/9;
            theta_SC=-pi/2:SC_step:(theta_n-pi/2);
            
            % Coordinates for Second Curve
            x_SC=cos(theta_SC)*0.382*Rt;
            y_SC=sin(theta_SC)*0.382*Rt+(0.382*Rt+Rt);
            x_SC1 = x_SC;
            y_SC1 = y_SC;
            theta_e=15*pi/180;
            
            % Angle for Third Curve
            x_TC=x_SC1(1,10);
            y_TC=y_SC1(1,10);
            y_exit=Dia_exit/2;
            matrix_y=[2*y_TC 1 0; 2*y_exit 1 0; y_TC^2 y_TC 1];
            matrix_x=[1/tan(theta_n); 1/tan(theta_e) ;x_TC];
            x_exit= matrix_y^-1*matrix_x;
            a=x_exit(1,1);
            b=x_exit(2,1);
            c=x_exit(3,1);
            yn=y_TC:.01:y_exit;
            xn= a*(yn).^2+b*yn+c;
            
            % Coordinate of Third Curve
            Rao_x=[x_FC1 x_SC1 xn];
            Rao_y=[y_FC1 y_SC1 yn];
            rao=[Rao_x Rao_y];
            
            %Final nozzle plot
            plot(app.UIAxes, Rao_x, Rao_y,Rao_x,-Rao_y);
            
            %Combustion chamber
            V_cc=L_starm*Th_Area;                                   %Chamber volume
            Dia_cc=((4*V_cc)/(2.5*3.14))^(1/3);                     %Chamber diameter
            L_cc=2.5*Dia_cc;                                        %Chamber length
            
            syms w z
            equat1= w-(app.p_1.Value*0.000145*z)/16000==0;            
            equat2= z+w/2-(Dia_cc)==0;
            [A,B]= equationsToMatrix([equat1,equat2],[w,z]);
            R= linsolve(A,B);
            Y = reshape(R.',1,[]);
            Y1=vpa(Y);
            Thickness= Y1(1,1);                                     %Thickness of chamber
            Mean_Diameter=Y1(1,2);                                  %Mean diameter of chamber                 
            L_conver=(Dia_cc-Th_Dia)/(2*(tand(theta_conver)));                              %Length of convergent part (not the slant height, but straight one)  
            V_conver=(1/3)*3.14*L_conver*((Th_Dia/2)^2+(Dia_cc/2)*(Th_Dia/2)+(Dia_cc/2)^2); %Volume of the convergent part            
            V_cyl=V_cc-V_conver;                                                            % Volume of cylindrical part            
            L1=(V_cyl-V_conver)/((3.14/4)*Dia_cc^2);                                        %Length of cylindrical part
        end
    
        %Conical nozzle and Chamber function
        function[Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_conic(app,T_1,R,M,g,L_starm,p_e,p_a)
            
            %Description:
            
            theta_conver=45;
            Ve = (((2*g*R*T_1)/(M*g-M))*(1-(p_e/app.p_1.Value)^((g-1)/g)))^0.5;         %Exit velocity
            Me = sqrt((2/(g-1))*((app.p_1.Value/p_e)^((g-1)/g)-1));                     %Exit Mach number
            e= (1/Me)*(((2/(g+1))*(1+((g-1)/2)*Me^2)))^((g+1)/(2*(g-1)));               %Expansion ratio
            a = (2*g^2)/(g-1);
            b = (2/(g+1))^((g+1)/(g-1));
            c = 1-(p_e/app.p_1.Value)^((g-1)/g);
            Cf = sqrt(a*b*c)+(((p_e-p_a)/app.p_1.Value)*(e));                           %Thrust coeff
            Th_Area = app.FT.Value/(app.p_1.Value * Cf);                                %Throat area
            A_exit = Th_Area * e;                                                       %Exit area
            Dia_exit=((4*A_exit)/3.14)^(0.5);                                           %Exit diameter                      
            Th_Dia=((4* Th_Area)/3.14)^(0.5);                                           %Throat diameter
            Rt= Th_Dia/2;
            m_dot1 = (Th_Area*app.p_1.Value)/((R/M)*T_1)^0.5;
            m_dot2 = g*((2/(g+1))^((g+1)/(g-1)));
            m_dot = m_dot1 * m_dot2^0.5;                                                %Mass flow rate
            Isp = app.FT.Value/(m_dot * 9.81);                                          %Specific impulse
            
            %Nozzle plot
            div = 15;
            Ln= (sqrt(e)-1)*Rt/tand(15);
            
            %First curve
            theta_n=15;
            angf_n=theta_n*pi/180;
            covanF=-3*pi/4;
            Fstep = ((angf_n-pi/2)- covanF)/9;
            thetaFc= (covanF):Fstep:(angf_n-pi/2);
            
            %coordinates
            x_fc = cos(thetaFc)*1.5*Rt;
            x_fcn= cos(angf_n-(pi/2))*1.5*Rt;
            y_fc = sin(thetaFc)*1.5*Rt+(1.5*Rt+Rt);
            y_fcn= sin(angf_n-(pi/2))*1.5*Rt+(1.5*Rt+Rt);
            
            %second curve
            ca = tan(angf_n);
            cb =  y_fcn-(ca*x_fcn);
            stepc2 = (Ln-x_fcn)/9;
            x_sc = x_fcn:stepc2:Ln;
            y_sc = ca*x_sc+cb;
            
            %Third curve
            comb_twox =[x_fc x_sc] ;
            comb_twoy = [y_fc y_sc];
            
            %Combustion chamber
            V_cc=L_starm*Th_Area;                                   %Voume of chamber
            Dia_cc=((4*V_cc)/(2.5*3.14))^(1/3);                     %Diameter of chamber
            L_cc=2.5*Dia_cc;                                        %Length of chamber            
            
            syms w z
            equat1= w-(app.p_1.Value*0.000145*z)/16000==0;            
            equat2= z+w/2-(Dia_cc)==0;
            [A,B]= equationsToMatrix([equat1,equat2],[w,z]);
            R= linsolve(A,B);
            Y = reshape(R.',1,[]);
            Y1=vpa(Y);
            Thickness= Y1(1,1);                                     %Thickness of chamber
            Mean_Diameter=Y1(1,2);                                  %Mean diameter of chamber                
            L_conver=(Dia_cc-Th_Dia)/(2*(tand(theta_conver)));                                  %Length of convergent part (not the slant height, but straight one).       
            V_conver=(1/3)*3.14*L_conver*((Th_Dia/2)^2+(Dia_cc/2)*(Th_Dia/2)+(Dia_cc/2)^2);     %Volume of the convergent part        
            V_cyl=V_cc-V_conver;                                                                %Volume of cylindrical part
            L1=(V_cyl-V_conver)/((3.14/4)*Dia_cc^2);                                            %Length of cylindrical part
            
            %Final nozzle plot
            plot(app.UIAxes,comb_twox,comb_twoy,comb_twox,-comb_twoy)        
        end 
        
        %Injector design function
        function [delPo,delPf,Vo,Vf,n,n1,do,df] = Injector(app,m_dot,r,po,pf)
        
            %Description:
            %Outer loop(while loop) for increasing n(hole count) then 
            %inner loop(for loop) for calculations at different diameter values
            %when criteria is met the loop breaks by the use of if statement
            % Outputs: Pressure drop , Velocity, Holes, Diameter of orifice
            % Inputs: Mass flow rate, Mixture ratio, Chamber pressure, Density
            
            Cd=0.87;                            %Discharge coefficient (no unit)
            LPc=0.15*app.p_1.Value;             %Lower threshold 15%of Chamber pressure   (CHANGE)
            HPc=0.25*app.p_1.Value;             %Higher threshold 25%of Chamber pressure   (CHANGE)
            mo = (m_dot*r)/(r+1) ;              %Mass flow rate of oxidizer kg/s
            mf = m_dot/(r+1)  ;                 %Mass flow rate of fuel kg/s
            Qo=mo/po ;                          %Volumetric flow rate of oxidizer m3/s
            Qf=mf/pf   ;                        %Volumetric flow rate of fuel m3/s
            O = 0   ;                           %Variable to stop the loop
            F = 0  ;                            %Variable to stop the loop
            n=0  ;
            n1=0    ;                           %No. of holes
            
            while(F==0)                         %FUEL
                n1=n1+10;
                
             for df=0.5:0.25:5.0             
                    af=(3.14/4)*(df*1e-3)^2;
                    Af=1.1*af*n1;
                    delPf=((Qf/(Cd*Af))^2)*(pf/2);
                    Vf=mf/(pf*Af);
                    if((LPc<=delPf)&&(delPf<=HPc))
                        F=1;
                        break
                    end
              end
            end
            
            while(O==0)                           %OXIDIZER 
                n=n+10;
                for do=0.5:0.25:5.0            
                    ao=(3.14/4)*(do*1e-3)^2;
                    Ao=ao*n;
                    delPo=((Qo/(Cd*Ao))^2)*(po/2);
                    Vo=mo/(po*Ao);
                    if((LPc<=delPo)&&(delPo<=HPc))
                        O=1;
                        break
                    end
                end
            end
        end 

        %Tank design function
        function [Totmassprop,ms_ox,ms_fuel,ts_ox,ts_fuel,rs_ox,rs_fuel,Vp,volox,volfuel,Max_allowable_stress]= propellanttanks(app,pf,po,r,go,Isp,Max_allowable_stress,rho,delPo,delPf)                       
            
            %Description:
            
            pi=3.14;
            
            %Determination of mass of propellants
            mfinal=app.minitial.Value/(exp(app.deltav.Value/(go*Isp)));         %Ideal rocket equation
            mprop=app.minitial.Value-mfinal;                                    
            mfuel=mprop/r;                                                      %Fuel mass
            mox=mprop-mfuel;                                                    %Oxidizer mass
            
            %Volume of tanks with 10% extra as ullage volume
            volox=1.1*(mox/po);                                                 %Volume of oxidizer
            volfuel=1.1*(mfuel/pf);                                             %Volume of fuel
            Vp=volox+volfuel;                                                   %Total Voulme    
            
            %Pressure calculations
            delpdyn_ox=0.5*po*100;                                              %change dynamic pressure  
            delpdyn_fuel=0.5*pf*100;                                            %change dynamic pressure 
            delpfeed=42500;                                                     %change feed pressure  
            sf=2;                                                               %safety factor
            maxop_ox=sf*(app.p_1.Value+delpdyn_ox+delpfeed+delPo);            %max expected operating pressure (burst)
            maxop_fuel=sf*(app.p_1.Value+delpdyn_fuel+delpfeed+delPf);        %max expected operating pressure (burst)
            
            %Dimensions and mass of the tanks
            rs_fuel=((3/4)*volfuel/pi)^(1/3);                           %Radius of fuel
            rs_ox=((3/4)*volox/pi)^(1/3);                               %Radius of oxidizer
            ts_fuel=((maxop_fuel*rs_fuel)/(2*Max_allowable_stress));    %thickness of sphere 2 endcaps of fuel 
            ts_ox=((maxop_ox*rs_ox)/(2*Max_allowable_stress));          %thickness of sphere 2 endcaps of ox         
            ms_ox=(((4/3)*pi*(rs_ox+ts_ox)^(3)-volox)*rho);             
            ms_fuel=(((4/3)*pi*(rs_fuel+ts_fuel)^(3)-volfuel)*rho);
            mtank_ox=ms_ox;                                             %mass of each oxidizer tank 
            mtank_fuel=ms_fuel;                                         %mass of each fuel tank 
            
            
            Totmassprop=mtank_ox+mtank_fuel+mfuel+mox;                  
            
        end
        
        %Turbopump function
        function [S,Q,T,J]=turbopum(app,m_dot,po,pf,g,r)
            
            %Description:
            
            mass_flow_rate_of_fuel=m_dot/(r+1);                                 %Mass flow rate of fuel
            mass_flow_rate_of_oxidizer=(m_dot*r)/(r+1);                         %Mass flow rate of oxidizer
            
            %PUMP POWER FOR OXIDIZER
            a=mass_flow_rate_of_oxidizer;  
            b=100*100000;                                                       %pressure rise in oxidizer pump (100 bar) 
            d=0.8;                                                              %pump efficiency
            S=(a*b)/(po*d);                                                     %Pump power for oxidizer	
            
            
            %PUMP POWER FOR FUEL
            e=mass_flow_rate_of_fuel;   
            f=115.5*100000;                                                     %pressure rise in oxidizer pump (100 bar)	                                                       
            h=0.8;                                                              %pump efficiency 
            Q=(e*f)/(pf*h);                                                     %Pump power for fuel
            
            
            %POWER OF TURBINE  
            i=0.975;                                                            %mechanical efficiency of turbine
            T=(S+Q)/i;                                                          %Power of turbine 
            
            
            %MASS FLOW RATE OF TURBINE
            j=0.7;                                                              %turbine Efficiency
            k=1.02;                                                             %coefficient of pressure
            l=1000;                                                             %inlet temperature
            m=20;                                                               %turbine pressure rat
            J=(T/(j*k*l*(1-(1/m)^((g-1)/g))))/60;                               %Mass Flow Rate of turbine        
        end 
        
        %Pressure feed function
        function [V,Mp,Rpt,Tpt,Mpt]= pressurefeed(app,Vp,po,pf,Max_allowable_stress,delPo,delPf)
        
        %Description:
        
        Pox=po*8.314*90;                    %Oxidizer tank pressure
        Pfuel=pf*8.314*293;                 %Fuel tank pressure
           
        Pp=Pox+Pfuel;                       %Total pressure of propellant tanks (pascals)  
        Pg=2000000;                         %Initial pressure of Pressurant gas (Pg) 
        
        
        %Calculating Volume of the pressurant tank
        Vg=Pp*Vp/Pg;
        Ullage_volume = 0.1*Vg;
        V= Vg+ Ullage_volume;                       %Total Pressurant gas tank volume 
        
        %Finding mass of pressurant gas                   
        R=0.08206;                         %R is the Gas constant L atm K^-1 mol^-1
        T=4;                               %T is temperature of Helium gas K
        n=(Pg*9.86923e-6*V*1000)/(R*T);    %No of moles (n)=(Pg*V)/(R*T)  
        Mp=n*4.002602*0.001*100;               %Mass of gas 
                                               
        %Pressure calculations
        pi=3.14;
        rho_he=999.84;                              %Density of helium              
        delpinj=(delPo+delPf)/2;                 %change injection pressure (Humble) Pa
        sf=2;                                       %safety factor
        maxop_pre=2.5*(1e5+delpinj)*sf;             %max expected operating pressure (burst)
        
        %Tank mass and dimensions
        Rpt=((3/4)*V/pi)^(1/3);                                 %Radius   
        Tpt=((maxop_pre*Rpt)/(2*Max_allowable_stress));         %Thickness   
        Mpt=(((4/3)*pi*(Rpt+Tpt)^(3)-V)*rho_he);                %Mass                                                           
    end 

    end       

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateButton, ExpansionRatioEditField
        function CalculateButtonPushed(app, event)
        
            app.TabGroup2.Visible='on';
            app.RunthetooltoseetheoutputsLabel.Visible='off';
            
            R=8.314;
            go=9.8;
            
            app.FT.Value=app.ThrustinNEditField.Value;
            app.p_1.Value=app.ChamberPressureinPaEditField.Value;
            app.minitial.Value=app.InitialMassinKgEditField.Value;
            app.deltav.Value=app.MissionDeltaVinmsEditField.Value;
            app.option.Value=str2double(app.PropellantsDropDown.Value);
            
            data=xlsread('PROPELLANT DATA.xlsx');
            for i=1:1:12
                if(i==app.option.Value)
                    M=data(i,3);         %Molecula_Weight_gmol
                    r=data(i,4);         %Mixtur_Ratio
                    T_1=data(i,5);       %T_c K
                    g=data(i,6);         %Gamma
                    C_starms=data(i,7);  %C_starms;;
                    po=data(i,8);        %Density_of_Oxidizer
                    pf=data(i,9);        %Density_of_Fuel
                    L_starm=data(i,10);  %L_star  
    
                end
            end
            
            if(app.BellCheckBox.Value==1)
                if(app.SealevelCheckBox.Value==1)
                    p_a = 101325; 
                    p_e = 121590; 
                 
                    [Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_bell(app,T_1,R,M,g,L_starm,p_e,p_a);
                else
                    p_a = 0;
                    p_e = 10000;
                 
                    [Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_bell(app,T_1,R,M,g,L_starm,p_e,p_a);
                end
            else
                if(app.SealevelCheckBox.Value==1)
                    p_a = 101325; 
                    p_e = 121590; 
                 
                    [Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_conic(app,T_1,R,M,g,L_starm,p_e,p_a);
                else
                    p_a = 0;
                    p_e = 10000;
                 
                    [Me,e,Dia_exit,Th_Dia,Th_Area,m_dot,Cf,Ve,Isp,Dia_cc,L_cc,V_cc,V_cyl,L1,Thickness,Mean_Diameter]= int_conic(app,T_1,R,M,g,L_starm,p_e,p_a);
                end
            end
         
                [delPo,delPf,Vo,Vf,n,n1,do,df] = Injector(app,m_dot,r,po,pf);
                
                app.TurbopumpFeedPanel.Visible='on';
                app.PressureFeedPanel.Visible='on';
                
            if(app.AluminiumCheckBox.Value==1)
                Max_allowable_stress=137500000; 
                rho=2710;  
                    
                [Totmassprop,ms_ox,ms_fuel,ts_ox,ts_fuel,rs_ox,rs_fuel,Vp,volox,volfuel,Max_allowable_stress]= propellanttanks(app,pf,po,r,go,Isp,Max_allowable_stress,rho,delPo,delPf);
                    
                if(app.TurbopumpCheckBox.Value==1)
                    [S,Q,T,J]=turbopum(app,m_dot,po,pf,g,r);
                        
                     app.PowerofturbineinWEditField.Value=T;
                     app.MassflowturbineinkgsEditField.Value=J;
                     app.PowerOxidizerpumpinWEditField.Value=S;
                     app.PowerFuelpumpinWEditField.Value=Q;
                else
                     [V,Mp,Rpt,Tpt,Mpt]= pressurefeed(app,Vp,po,pf,Max_allowable_stress,delPo,delPf);
                        
                     app.Pressurizingtankvolumeinm3EditField.Value=V;
                     app.PressurizingtankmassinkgEditField.Value=Mpt;
                     app.TankthicknessinmEditField.Value=Tpt;
                     app.TankradiusinmEditField.Value=Rpt;
                     app.PressurizinggasmassinkgEditField.Value=Mp;
                 end
            else
                 Max_allowable_stress=240000000;  
                 rho=4420;
                    
                 [Totmassprop,ms_ox,ms_fuel,ts_ox,ts_fuel,rs_ox,rs_fuel,Vp,volox,volfuel,Max_allowable_stress]= propellanttanks(app,pf,po,r,go,Isp,Max_allowable_stress,rho,delPo,delPf);
                    
                 if(app.TurbopumpCheckBox.Value==1)
                     [S,Q,T,J]=turbopum(app,m_dot,po,pf,g,r);
                        
                     app.PowerofturbineinWEditField.Value=T;
                     app.MassflowturbineinkgsEditField.Value=J;
                     app.PowerOxidizerpumpinWEditField.Value=S;
                     app.PowerFuelpumpinWEditField.Value=Q;
                     app.PressureFeedPanel.Visible='off';
                 else
                     [V,Mp,Rpt,Tpt,Mpt]= pressurefeed(app,Vp,po,pf,Max_allowable_stress,delPo,delPf);
                        
                     app.Pressurizingtankvolumeinm3EditField.Value=V;
                     app.PressurizingtankmassinkgEditField.Value=Mpt;
                     app.TankthicknessinmEditField.Value=Tpt;
                     app.TankradiusinmEditField.Value=Rpt;
                     app.PressurizinggasmassinkgEditField.Value=Mp;
                     app.TurbopumpFeedPanel.Visible='off';
                
                 end
            end


                app.ExitMachnumberEditField.Value=Me;
                app.ExpansionRatioEditField.Value=e;
                app.ExitdiameterinmEditField.Value=Dia_exit;
                app.ThroatAreainm2EditField.Value=Th_Area;
                app.MassflowrateinkgsEditField.Value=m_dot;
                app.CoefficientofThrustEditField.Value=Cf;
                app.ExitVelocityinmsEditField.Value=Ve;
                app.SpecificImpulseinsEditField.Value=Isp;
                app.ThroatDiameterinmEditField.Value=Th_Dia;
                app.DiameterinmEditField.Value=double(Dia_cc);
                app.LengthinmEditField.Value=double(L_cc);
                app.Volumeinm3EditField.Value=double(V_cc);
                app.Volumeinm3EditField_2.Value=double(V_cyl);
                app.LengthinmEditField_2.Value=double(L1);
                app.ThicknessinmEditField.Value=double(Thickness);
                app.MeanDiameterinmEditField.Value=double(Mean_Diameter);
                app.PressureDropOxidizerinPaEditField.Value=delPo;
                app.PressureDropFuelinPaEditField.Value=delPf;
                app.VelocityfromOxidizerorificeinmsEditField.Value=Vo;
                app.VelocityfromFuelorificeinmsEditField.Value=Vf;
                app.NumberofOxidizerholesEditField.Value=n;
                app.NumberofFuelholesEditField.Value=n1;
                app.DiameterofOxidizerorificeinmEditField.Value=do;
                app.DiameterofFuelorificeinmEditField.Value=df;
                app.MassinkgEditField.Value=Totmassprop;
                app.Volumeinm3EditField_3.Value=Vp;
                app.MassinkgEditField_2.Value=ms_ox;
                app.MassinkgEditField_3.Value=ms_fuel;
                app.ThicknessinmEditField_2.Value=ts_ox;
                app.ThicknessinmEditField_3.Value=ts_fuel;
                app.Volumeinm3EditField_4.Value=volox;
                app.Volumeinm3EditField_5.Value=volfuel;
                app.RadiusinmEditField.Value=rs_ox;
                app.RadiusinmEditField_2.Value=rs_fuel;
        end

        % Value changed function: ShowPropellantsCheckBox
        function ShowPropellantsCheckBoxValueChanged(app, event)
            value = app.ShowPropellantsCheckBox.Value;
            if(value==1)
                app.TextArea_4.Visible='on';
            else
                app.TextArea_4.Visible='off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STARSUIFigure = uifigure('Visible', 'off');
            app.STARSUIFigure.Name = 'STARS';
            app.TabGroup = uitabgroup(app.STARSUIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.TextArea = uitextarea(app.WelcomeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Hello user, Welcome to Simplified Tool for Analysis of  Rocket Systems (STARS).'; ''; ''; 'STARS is an tool created by Team DHRUV which aids in design sizing of Liquid Rocket engine systems by providing the user with basic design and performance parameters for LRE.'; ''; 'STARS splits up the LRE into different modules as:'; ''; '1.Nozzle'; '2.Combustion chamber'; '3.Injector'; '4.Feed system'; '5.Propellant tank design'; ''; 'and provides the design sizing and performance parameters for each separately.'; ''; ''; 'Right now, STARS has 12 propellant combinations which include RP-1, LOX, LH2 which are used the most in the current scenario. More combinations will be included in the future.'; ''; ''; ''; ''};
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.TextArea_2 = uitextarea(app.ManualTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'                                                                          STARS- GUIDE'; ''; '1.Go to the tab  named ''Inputs" to enter the inputs.'; ''; '2.The ''Inputs'' tab consists of  following Main Inputs to be provided by the user:'; ''; ' i.Thrust in Newtons'; ' ii.Combustion chamber pressure in Pascals'; ' iii. Specific Impulse Isp in seconds'; ' iv.Selection of propellants from the available propellant combinations.'; ''; 'Note: Currently, there are 12 available propellant combinations which are used the most by the industries now.More will be added soon.'; ''; '3. After entering the main inputs move on to the ''Selections''.'; ''; '4.In the selections there are four sub categories,namely:'; ''; ' i.Nozzle selection consists of selection of type of nozzle the user wants, either Bell or Conical.'; ' ii.Material selection consists of two materials Aluminum and Titanium to be selected by the user.'; ' iii.Feed system selection either Turbo pump or Pressure Feed system as per the user''s wish.'; ' iv.Mission Selection either Sea level conditions or Vacuum conditions.'; ''; '4.Once you''ve entered all the inputs press on the button ''Calculate''.'; ''; '5.All the outputs and the contour will be displayed on ''Outputs'' tab. Make sure you give all the necessary inputs before you press ''Calculate''. '; 'Note: Contents of outputs tab will not display unless calculations are performed.'; ''; ''; ''};
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.MainInputsPanel = uipanel(app.InputsTab);
            app.MainInputsPanel.Title = 'Main Inputs';
            app.ThrustinNLabel = uilabel(app.MainInputsPanel);
            app.ThrustinNLabel.Text = 'Thrust (in N)  ';
            app.ThrustinNEditField = uieditfield(app.MainInputsPanel, 'numeric');
            app.ThrustinNEditField.UpperLimitInclusive = 'off';
            app.ThrustinNEditField.Limits = [0 Inf];
            app.ChamberPressureinPaEditFieldLabel = uilabel(app.MainInputsPanel);
            app.ChamberPressureinPaEditFieldLabel.Text = 'Chamber Pressure (in Pa)  ';
            app.ChamberPressureinPaEditField = uieditfield(app.MainInputsPanel, 'numeric');
            app.ChamberPressureinPaEditField.Limits = [0 Inf];
            app.PropellantsDropDownLabel = uilabel(app.MainInputsPanel);
            app.PropellantsDropDownLabel.Text = 'Propellants';
            app.PropellantsDropDown = uidropdown(app.MainInputsPanel);
            app.PropellantsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.PropellantsDropDown.Value = '1';
            app.InitialMassinKgEditFieldLabel = uilabel(app.MainInputsPanel);
            app.InitialMassinKgEditFieldLabel.Text = 'Initial Mass (in Kg)';
            app.InitialMassinKgEditField = uieditfield(app.MainInputsPanel, 'numeric');
            app.InitialMassinKgEditField.Limits = [0 Inf];
            app.MissionDeltaVinmsEditFieldLabel = uilabel(app.MainInputsPanel);
            app.MissionDeltaVinmsEditFieldLabel.Text = 'Mission DeltaV (in m/s) ';
            app.MissionDeltaVinmsEditField = uieditfield(app.MainInputsPanel, 'numeric');
            app.MissionDeltaVinmsEditField.Limits = [0 Inf];
            app.SelectionsPanel = uipanel(app.InputsTab);
            app.SelectionsPanel.Title = 'Selections';
            app.BellCheckBox = uicheckbox(app.SelectionsPanel);
            app.BellCheckBox.Text = 'Bell';
            app.ConicalCheckBox = uicheckbox(app.SelectionsPanel);
            app.ConicalCheckBox.Text = 'Conical';
            app.AluminiumCheckBox = uicheckbox(app.SelectionsPanel);
            app.AluminiumCheckBox.Text = 'Aluminium';
            app.TitaniumCheckBox = uicheckbox(app.SelectionsPanel);
            app.TitaniumCheckBox.Text = 'Titanium';
            app.TurbopumpCheckBox = uicheckbox(app.SelectionsPanel);
            app.TurbopumpCheckBox.Text = 'Turbopump';
            app.PressureFeedCheckBox = uicheckbox(app.SelectionsPanel);
            app.PressureFeedCheckBox.Text = 'Pressure Feed';
            app.NozzleSelectionLabel = uilabel(app.SelectionsPanel);
            app.NozzleSelectionLabel.Text = 'Nozzle Selection';
            app.MaterialSelectionLabel = uilabel(app.SelectionsPanel);
            app.MaterialSelectionLabel.Text = 'Material Selection';
            app.FeedSystemLabel = uilabel(app.SelectionsPanel);
            app.FeedSystemLabel.Text = 'Feed System';
            app.SealevelCheckBox = uicheckbox(app.SelectionsPanel);
            app.SealevelCheckBox.Text = 'Sea level';
            app.VacuumCheckBox = uicheckbox(app.SelectionsPanel);
            app.VacuumCheckBox.Text = 'Vacuum';
            app.MissionLabel = uilabel(app.SelectionsPanel);
            app.MissionLabel.Text = 'Mission';
            app.CalculateButton = uibutton(app.InputsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TextArea_4 = uitextarea(app.InputsTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'PROPELLANT COMBINATIONS'; ''; '1	LOX/KEROSENE'; ''; '2	LOX/LH_2'; ''; '3	LOX/UDMH'; ''; '4	LOX/ETHYL ALCOHOL'; ''; '5	H2O2/KEROSENE'; ''; '6	N2O4/UDMH'; ''; '7	N2O4/MMH'; ''; '8	N2O4/KEROSENE'; ''; '9	Nitric acid/ Hydrazine'; ''; '10	LF/LH'; ''; '11	LOX/Ammonia'; ''; '12	Nitric acid/UDMH'};
            app.ShowPropellantsCheckBox = uicheckbox(app.InputsTab);
            app.ShowPropellantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPropellantsCheckBoxValueChanged, true);
            app.ShowPropellantsCheckBox.Text = 'Show Propellants';
            app.ShowPropellantsCheckBox.Value = true;
            app.FlowoftheprogramTab = uitab(app.TabGroup);
            app.FlowoftheprogramTab.Title = 'Flow of the program';
            app.Image4 = uiimage(app.FlowoftheprogramTab);
            app.Image4.ImageSource = 'peppers.PNG';
            app.OutputDisplayTab = uitab(app.TabGroup);
            app.OutputDisplayTab.Title = 'Output Display';
            app.TabGroup2 = uitabgroup(app.OutputDisplayTab);
            app.TabGroup2.Visible = 'off';
            app.NozzleTab = uitab(app.TabGroup2);
            app.NozzleTab.Title = 'Nozzle';
            app.ExpansionRatioEditFieldLabel = uilabel(app.NozzleTab);
            app.ExpansionRatioEditFieldLabel.Text = 'Expansion Ratio';
            app.ExpansionRatioEditField = uieditfield(app.NozzleTab, 'numeric');
            app.ExpansionRatioEditField.Limits = [0 Inf];
            app.ExpansionRatioEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.ExpansionRatioEditField.Editable = 'off';
            app.MassflowrateinkgsEditFieldLabel = uilabel(app.NozzleTab);
            app.MassflowrateinkgsEditFieldLabel.Text = 'Mass flow rate (in kg/s)';
            app.MassflowrateinkgsEditField = uieditfield(app.NozzleTab, 'numeric');
            app.MassflowrateinkgsEditField.Limits = [0 Inf];
            app.MassflowrateinkgsEditField.Editable = 'off';
            app.SpecificImpulseinsEditFieldLabel = uilabel(app.NozzleTab);
            app.SpecificImpulseinsEditFieldLabel.Text = 'Specific Impulse (in s)';
            app.SpecificImpulseinsEditField = uieditfield(app.NozzleTab, 'numeric');
            app.SpecificImpulseinsEditField.Limits = [0 Inf];
            app.SpecificImpulseinsEditField.Editable = 'off';
            app.CoefficientofThrustEditFieldLabel = uilabel(app.NozzleTab);
            app.CoefficientofThrustEditFieldLabel.Text = 'Coefficient of Thrust';
            app.CoefficientofThrustEditField = uieditfield(app.NozzleTab, 'numeric');
            app.CoefficientofThrustEditField.Limits = [0 Inf];
            app.CoefficientofThrustEditField.Editable = 'off';
            app.ExitCharacteristicsPanel = uipanel(app.NozzleTab);
            app.ExitCharacteristicsPanel.Title = 'Exit Characteristics';
            app.ExitdiameterinmEditFieldLabel = uilabel(app.ExitCharacteristicsPanel);
            app.ExitdiameterinmEditFieldLabel.Text = 'Exit diameter (in m)';
            app.ExitdiameterinmEditField = uieditfield(app.ExitCharacteristicsPanel, 'numeric');
            app.ExitdiameterinmEditField.Limits = [0 Inf];
            app.ExitdiameterinmEditField.Editable = 'off';
            app.ExitMachnumberEditFieldLabel = uilabel(app.ExitCharacteristicsPanel);
            app.ExitMachnumberEditFieldLabel.Text = 'Exit Mach number';
            app.ExitMachnumberEditField = uieditfield(app.ExitCharacteristicsPanel, 'numeric');
            app.ExitMachnumberEditField.Limits = [0 Inf];
            app.ExitMachnumberEditField.Editable = 'off';
            app.ExitVelocityinmsEditFieldLabel = uilabel(app.ExitCharacteristicsPanel);
            app.ExitVelocityinmsEditFieldLabel.Text = 'Exit Velocity (in m/s)';
            app.ExitVelocityinmsEditField = uieditfield(app.ExitCharacteristicsPanel, 'numeric');
            app.ExitVelocityinmsEditField.Limits = [0 Inf];
            app.ExitVelocityinmsEditField.Editable = 'off';
            app.ThroatCharacteristicsPanel = uipanel(app.NozzleTab);
            app.ThroatCharacteristicsPanel.Title = 'Throat Characteristics';
            app.ThroatAreainm2EditFieldLabel = uilabel(app.ThroatCharacteristicsPanel);
            app.ThroatAreainm2EditFieldLabel.Text = 'Throat Area (in m2)';
            app.ThroatAreainm2EditField = uieditfield(app.ThroatCharacteristicsPanel, 'numeric');
            app.ThroatAreainm2EditField.Limits = [0 Inf];
            app.ThroatAreainm2EditField.Editable = 'off';
            app.ThroatDiameterinmEditFieldLabel = uilabel(app.ThroatCharacteristicsPanel);
            app.ThroatDiameterinmEditFieldLabel.Text = 'Throat Diameter (in m)';
            app.ThroatDiameterinmEditField = uieditfield(app.ThroatCharacteristicsPanel, 'numeric');
            app.ThroatDiameterinmEditField.Limits = [0 Inf];
            app.ThroatDiameterinmEditField.Editable = 'off';
            app.NozzleContourTab = uitab(app.TabGroup2);
            app.NozzleContourTab.Title = 'Nozzle Contour';
            app.UIAxes = uiaxes(app.NozzleContourTab);
            title(app.UIAxes, 'Contour')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ChamberTab = uitab(app.TabGroup2);
            app.ChamberTab.Title = 'Chamber';
            app.ChamberPanel = uipanel(app.ChamberTab);
            app.ChamberPanel.Title = 'Chamber';
            app.Volumeinm3EditFieldLabel = uilabel(app.ChamberPanel);
            app.Volumeinm3EditFieldLabel.Text = 'Volume (in m3)';
            app.Volumeinm3EditField = uieditfield(app.ChamberPanel, 'numeric');
            app.Volumeinm3EditField.Limits = [0 Inf];
            app.Volumeinm3EditField.Editable = 'off';
            app.LengthinmEditFieldLabel = uilabel(app.ChamberPanel);
            app.LengthinmEditFieldLabel.Text = 'Length (in m)';
            app.LengthinmEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.LengthinmEditField.Limits = [0 Inf];
            app.LengthinmEditField.Editable = 'off';
            app.DiameterinmEditFieldLabel = uilabel(app.ChamberPanel);
            app.DiameterinmEditFieldLabel.Text = 'Diameter (in m)';
            app.DiameterinmEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.DiameterinmEditField.Limits = [0 Inf];
            app.DiameterinmEditField.Editable = 'off';
            app.ThicknessinmEditFieldLabel = uilabel(app.ChamberPanel);
            app.ThicknessinmEditFieldLabel.Text = 'Thickness (in m)';
            app.ThicknessinmEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.ThicknessinmEditField.Limits = [0 Inf];
            app.ThicknessinmEditField.Editable = 'off';
            app.MeanDiameterinmEditFieldLabel = uilabel(app.ChamberPanel);
            app.MeanDiameterinmEditFieldLabel.Text = 'Mean Diameter (in m)';
            app.MeanDiameterinmEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.MeanDiameterinmEditField.Limits = [0 Inf];
            app.MeanDiameterinmEditField.Editable = 'off';
            app.CylindricalpartPanel = uipanel(app.ChamberTab);
            app.CylindricalpartPanel.Title = 'Cylindrical part';
            app.Volumeinm3EditField_2Label = uilabel(app.CylindricalpartPanel);
            app.Volumeinm3EditField_2Label.Text = 'Volume (in m3)';
            app.Volumeinm3EditField_2 = uieditfield(app.CylindricalpartPanel, 'numeric');
            app.Volumeinm3EditField_2.Limits = [0 Inf];
            app.Volumeinm3EditField_2.Editable = 'off';
            app.LengthinmEditField_2Label = uilabel(app.CylindricalpartPanel);
            app.LengthinmEditField_2Label.Text = 'Length (in m)';
            app.LengthinmEditField_2 = uieditfield(app.CylindricalpartPanel, 'numeric');
            app.LengthinmEditField_2.Limits = [0 Inf];
            app.LengthinmEditField_2.Editable = 'off';
            app.InjectorTab = uitab(app.TabGroup2);
            app.InjectorTab.Title = 'Injector';
            app.OxidizerCharacteristicsPanel = uipanel(app.InjectorTab);
            app.OxidizerCharacteristicsPanel.Title = 'Oxidizer Characteristics';
            app.PressureDropOxidizerinPaEditFieldLabel = uilabel(app.OxidizerCharacteristicsPanel);
            app.PressureDropOxidizerinPaEditFieldLabel.Text = 'Pressure Drop Oxidizer (in Pa)';
            app.PressureDropOxidizerinPaEditField = uieditfield(app.OxidizerCharacteristicsPanel, 'numeric');
            app.PressureDropOxidizerinPaEditField.Editable = 'off';
            app.VelocityfromOxidizerorificeinmsEditFieldLabel = uilabel(app.OxidizerCharacteristicsPanel);
            app.VelocityfromOxidizerorificeinmsEditFieldLabel.Text = 'Velocity from Oxidizer orifice (in m/s)';
            app.VelocityfromOxidizerorificeinmsEditField = uieditfield(app.OxidizerCharacteristicsPanel, 'numeric');
            app.VelocityfromOxidizerorificeinmsEditField.Limits = [0 Inf];
            app.VelocityfromOxidizerorificeinmsEditField.Editable = 'off';
            app.DiameterofOxidizerorificeinmEditFieldLabel = uilabel(app.OxidizerCharacteristicsPanel);
            app.DiameterofOxidizerorificeinmEditFieldLabel.Text = 'Diameter of Oxidizer orifice (in m)';
            app.DiameterofOxidizerorificeinmEditField = uieditfield(app.OxidizerCharacteristicsPanel, 'numeric');
            app.DiameterofOxidizerorificeinmEditField.Limits = [0 Inf];
            app.DiameterofOxidizerorificeinmEditField.Editable = 'off';
            app.NumberofOxidizerholesEditFieldLabel = uilabel(app.OxidizerCharacteristicsPanel);
            app.NumberofOxidizerholesEditFieldLabel.Text = 'Number of Oxidizer holes';
            app.NumberofOxidizerholesEditField = uieditfield(app.OxidizerCharacteristicsPanel, 'numeric');
            app.NumberofOxidizerholesEditField.Limits = [0 Inf];
            app.NumberofOxidizerholesEditField.Editable = 'off';
            app.FuelCharacteristicsPanel = uipanel(app.InjectorTab);
            app.FuelCharacteristicsPanel.Title = 'Fuel Characteristics';
            app.PressureDropFuelinPaEditFieldLabel = uilabel(app.FuelCharacteristicsPanel);
            app.PressureDropFuelinPaEditFieldLabel.Text = 'Pressure Drop Fuel (in Pa)';
            app.PressureDropFuelinPaEditField = uieditfield(app.FuelCharacteristicsPanel, 'numeric');
            app.PressureDropFuelinPaEditField.Limits = [0 Inf];
            app.PressureDropFuelinPaEditField.Editable = 'off';
            app.VelocityfromFuelorificeinmsEditFieldLabel = uilabel(app.FuelCharacteristicsPanel);
            app.VelocityfromFuelorificeinmsEditFieldLabel.Text = 'Velocity from Fuel orifice (in m/s)';
            app.VelocityfromFuelorificeinmsEditField = uieditfield(app.FuelCharacteristicsPanel, 'numeric');
            app.VelocityfromFuelorificeinmsEditField.Limits = [0 Inf];
            app.VelocityfromFuelorificeinmsEditField.Editable = 'off';
            app.DiameterofFuelorificeinmEditFieldLabel = uilabel(app.FuelCharacteristicsPanel);
            app.DiameterofFuelorificeinmEditFieldLabel.Text = 'Diameter of Fuel orifice (in m)';
            app.DiameterofFuelorificeinmEditField = uieditfield(app.FuelCharacteristicsPanel, 'numeric');
            app.DiameterofFuelorificeinmEditField.Limits = [0 Inf];
            app.DiameterofFuelorificeinmEditField.Editable = 'off';
            app.NumberofFuelholesEditFieldLabel = uilabel(app.FuelCharacteristicsPanel);
            app.NumberofFuelholesEditFieldLabel.Text = 'Number of Fuel holes';
            app.NumberofFuelholesEditField = uieditfield(app.FuelCharacteristicsPanel, 'numeric');
            app.NumberofFuelholesEditField.Limits = [0 Inf];
            app.NumberofFuelholesEditField.Editable = 'off';
            app.FeedsystemTab = uitab(app.TabGroup2);
            app.FeedsystemTab.Title = 'Feed system';
            app.TurbopumpFeedPanel = uipanel(app.FeedsystemTab);
            app.TurbopumpFeedPanel.Title = 'Turbopump Feed';
            app.PowerofturbineinWEditFieldLabel = uilabel(app.TurbopumpFeedPanel);
            app.PowerofturbineinWEditFieldLabel.Text = 'Power of turbine (in W)';
            app.PowerofturbineinWEditField = uieditfield(app.TurbopumpFeedPanel, 'numeric');
            app.PowerofturbineinWEditField.Limits = [0 Inf];
            app.PowerofturbineinWEditField.Editable = 'off';
            app.MassflowturbineinkgsEditFieldLabel = uilabel(app.TurbopumpFeedPanel);
            app.MassflowturbineinkgsEditFieldLabel.Text = 'Mass flow turbine (in kg/s)';
            app.MassflowturbineinkgsEditField = uieditfield(app.TurbopumpFeedPanel, 'numeric');
            app.MassflowturbineinkgsEditField.Limits = [0 Inf];
            app.MassflowturbineinkgsEditField.Editable = 'off';
            app.PowerOxidizerpumpinWEditFieldLabel = uilabel(app.TurbopumpFeedPanel);
            app.PowerOxidizerpumpinWEditFieldLabel.Text = 'Power Oxidizer pump (in W)';
            app.PowerOxidizerpumpinWEditField = uieditfield(app.TurbopumpFeedPanel, 'numeric');
            app.PowerOxidizerpumpinWEditField.Limits = [0 Inf];
            app.PowerOxidizerpumpinWEditField.Editable = 'off';
            app.PowerFuelpumpinWEditFieldLabel = uilabel(app.TurbopumpFeedPanel);
            app.PowerFuelpumpinWEditFieldLabel.Text = 'Power Fuel pump (in W)';
            app.PowerFuelpumpinWEditField = uieditfield(app.TurbopumpFeedPanel, 'numeric');
            app.PowerFuelpumpinWEditField.Limits = [0 Inf];
            app.PowerFuelpumpinWEditField.Editable = 'off';
            app.PressureFeedPanel = uipanel(app.FeedsystemTab);
            app.PressureFeedPanel.Title = 'Pressure Feed';
            app.Pressurizingtankvolumeinm3EditFieldLabel = uilabel(app.PressureFeedPanel);
            app.Pressurizingtankvolumeinm3EditFieldLabel.Text = 'Pressurizing tank volume (in m3)';
            app.Pressurizingtankvolumeinm3EditField = uieditfield(app.PressureFeedPanel, 'numeric');
            app.Pressurizingtankvolumeinm3EditField.Limits = [0 Inf];
            app.Pressurizingtankvolumeinm3EditField.Editable = 'off';
            app.PressurizingtankmassinkgEditFieldLabel = uilabel(app.PressureFeedPanel);
            app.PressurizingtankmassinkgEditFieldLabel.Text = 'Pressurizing tank mass (in kg)';
            app.PressurizingtankmassinkgEditField = uieditfield(app.PressureFeedPanel, 'numeric');
            app.PressurizingtankmassinkgEditField.Limits = [0 Inf];
            app.PressurizingtankmassinkgEditField.Editable = 'off';
            app.TankradiusinmEditFieldLabel = uilabel(app.PressureFeedPanel);
            app.TankradiusinmEditFieldLabel.Text = 'Tank radius (in m)';
            app.TankradiusinmEditField = uieditfield(app.PressureFeedPanel, 'numeric');
            app.TankradiusinmEditField.Limits = [0 Inf];
            app.TankradiusinmEditField.Editable = 'off';
            app.TankthicknessinmEditFieldLabel = uilabel(app.PressureFeedPanel);
            app.TankthicknessinmEditFieldLabel.Text = 'Tank thickness (in m)';
            app.TankthicknessinmEditField = uieditfield(app.PressureFeedPanel, 'numeric');
            app.TankthicknessinmEditField.Limits = [0 Inf];
            app.TankthicknessinmEditField.Editable = 'off';
            app.PressurizinggasmassinkgEditFieldLabel = uilabel(app.PressureFeedPanel);
            app.PressurizinggasmassinkgEditFieldLabel.Text = 'Pressurizing gas mass (in kg)';
            app.PressurizinggasmassinkgEditField = uieditfield(app.PressureFeedPanel, 'numeric');
            app.PressurizinggasmassinkgEditField.Limits = [0 Inf];
            app.PressurizinggasmassinkgEditField.Editable = 'off';
            app.TankDesignTab = uitab(app.TabGroup2);
            app.TankDesignTab.Title = 'Tank Design';
            app.TotalPanel = uipanel(app.TankDesignTab);
            app.TotalPanel.Title = 'Total';
            app.MassinkgEditFieldLabel = uilabel(app.TotalPanel);
            app.MassinkgEditFieldLabel.Text = 'Mass (in kg)';
            app.MassinkgEditField = uieditfield(app.TotalPanel, 'numeric');
            app.MassinkgEditField.Limits = [0 Inf];
            app.MassinkgEditField.Editable = 'off';
            app.Volumeinm3EditField_3Label = uilabel(app.TotalPanel);
            app.Volumeinm3EditField_3Label.Text = 'Volume (in m3)';
            app.Volumeinm3EditField_3 = uieditfield(app.TotalPanel, 'numeric');
            app.Volumeinm3EditField_3.Limits = [0 Inf];
            app.Volumeinm3EditField_3.Editable = 'off';
            app.OxidizertankPanel = uipanel(app.TankDesignTab);
            app.OxidizertankPanel.Title = 'Oxidizer tank';
            app.MassinkgEditField_2Label = uilabel(app.OxidizertankPanel);
            app.MassinkgEditField_2Label.Text = 'Mass (in kg)';
            app.MassinkgEditField_2 = uieditfield(app.OxidizertankPanel, 'numeric');
            app.MassinkgEditField_2.Limits = [0 Inf];
            app.MassinkgEditField_2.Editable = 'off';
            app.ThicknessinmEditField_2Label = uilabel(app.OxidizertankPanel);
            app.ThicknessinmEditField_2Label.Text = 'Thickness (in m)';
            app.ThicknessinmEditField_2 = uieditfield(app.OxidizertankPanel, 'numeric');
            app.ThicknessinmEditField_2.Limits = [0 Inf];
            app.ThicknessinmEditField_2.Editable = 'off';
            app.Volumeinm3EditField_4Label = uilabel(app.OxidizertankPanel);
            app.Volumeinm3EditField_4Label.Text = 'Volume (in m3)';
            app.Volumeinm3EditField_4 = uieditfield(app.OxidizertankPanel, 'numeric');
            app.Volumeinm3EditField_4.Limits = [0 Inf];
            app.Volumeinm3EditField_4.Editable = 'off';
            app.RadiusinmEditFieldLabel = uilabel(app.OxidizertankPanel);
            app.RadiusinmEditFieldLabel.Text = 'Radius (in m)';
            app.RadiusinmEditField = uieditfield(app.OxidizertankPanel, 'numeric');
            app.RadiusinmEditField.Limits = [0 Inf];
            app.RadiusinmEditField.Editable = 'off';
            app.FueltankPanel = uipanel(app.TankDesignTab);
            app.FueltankPanel.Title = 'Fuel tank';
            app.MassinkgEditField_3Label = uilabel(app.FueltankPanel);
            app.MassinkgEditField_3Label.Text = 'Mass (in kg)';
            app.MassinkgEditField_3 = uieditfield(app.FueltankPanel, 'numeric');
            app.MassinkgEditField_3.Limits = [0 Inf];
            app.MassinkgEditField_3.Editable = 'off';
            app.Volumeinm3EditField_5Label = uilabel(app.FueltankPanel);
            app.Volumeinm3EditField_5Label.Text = 'Volume (in m3)';
            app.Volumeinm3EditField_5 = uieditfield(app.FueltankPanel, 'numeric');
            app.Volumeinm3EditField_5.Limits = [0 Inf];
            app.Volumeinm3EditField_5.Editable = 'off';
            app.ThicknessinmEditField_3Label = uilabel(app.FueltankPanel);
            app.ThicknessinmEditField_3Label.Text = 'Thickness (in m)';
            app.ThicknessinmEditField_3 = uieditfield(app.FueltankPanel, 'numeric');
            app.ThicknessinmEditField_3.Limits = [0 Inf];
            app.ThicknessinmEditField_3.Editable = 'off';
            app.RadiusinmEditField_2Label = uilabel(app.FueltankPanel);
            app.RadiusinmEditField_2Label.Text = 'Radius (in m)';
            app.RadiusinmEditField_2 = uieditfield(app.FueltankPanel, 'numeric');
            app.RadiusinmEditField_2.Limits = [0 Inf];
            app.RadiusinmEditField_2.Editable = 'off';
            app.RunthetooltoseetheoutputsLabel = uilabel(app.OutputDisplayTab);
            app.RunthetooltoseetheoutputsLabel.Text = 'Run the tool to see the outputs!!';
            app.AboutUsTab = uitab(app.TabGroup);
            app.AboutUsTab.Title = 'About Us';
            app.TextArea_3 = uitextarea(app.AboutUsTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Team Dhruv, comprises of nine people all with single goal in mind'; 'Contributing our best to the space farring  age of human beings in anyway possible!'; ''; 'Our goal is to help you reach higher heights and shine bright in the night sky much like the polestar Dhruva.'; ''; 'The stars of our team'; ''; 'Yogesh PK '; 'Preethi Sridhar'; 'Bhavith Sai'; 'Gayatri'; 'Apoorva'; 'Anagha'; 'Ayush'; 'Rajesh'; 'Pranav'; ''; 'Our sincere gratitude to Society for Space Education Research and Development (SSERD) and the mentors, co-ordinators for without them this would not have been possible!'; ''};
            app.TeamDHRUVLabel = uilabel(app.AboutUsTab);
            app.TeamDHRUVLabel.Text = 'Team DHRUV';
            app.Image3 = uiimage(app.AboutUsTab);
            app.Image3.ImageSource = 'druv logo.jpeg';
            % Show the figure after all components are created
            app.STARSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_appp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STARSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STARSUIFigure)
        end
    end
end